classdef Sound_Modifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RecordButton                  matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        DurationLabel                 matlab.ui.control.Label
        DurationTextArea              matlab.ui.control.TextArea
        SpeedKnobLabel                matlab.ui.control.Label
        SpeedKnob                     matlab.ui.control.Knob
        SlowLabel                     matlab.ui.control.Label
        FastLabel                     matlab.ui.control.Label
        DesignedByShobhitSharmaLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            audioObject = audiorecorder;
            duration = str2double(app.DurationTextArea.Value{1});
            msgbox('Recording Started');
            recordblocking(audioObject,duration);
            msgbox('Recording Stopped');
            assignin('base',"audioObject",audioObject);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            audioObject = evalin('base', 'audioObject');
            y = getaudiodata(audioObject);
            Fs = audioObject.SampleRate;
            multiplier = app.SpeedKnob.Value;
            sound(y,Fs*multiplier);
%            play(audioObject)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.DurationLabel = uilabel(app.UIFigure);
            app.DurationLabel.Text = 'Duration   :';
            app.DurationTextArea = uitextarea(app.UIFigure);
            app.DurationTextArea.Value = {'5'};
            app.SpeedKnobLabel = uilabel(app.UIFigure);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.UIFigure, 'continuous');
            app.SpeedKnob.Limits = [0 2];
            app.SpeedKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.SpeedKnob.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1', '1.2', '1.4', '1.6', '1.8', '2'};
            app.SpeedKnob.Value = 1;
            app.SlowLabel = uilabel(app.UIFigure);
            app.SlowLabel.Text = 'Slow';
            app.FastLabel = uilabel(app.UIFigure);
            app.FastLabel.Text = 'Fast';
            app.DesignedByShobhitSharmaLabel = uilabel(app.UIFigure);
            app.DesignedByShobhitSharmaLabel.Text = 'Designed By: Shobhit Sharma';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'tvThuMmoTtmRU9pgJLJl_animated-gif-music.gif';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sound_Modifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end